classdef gantry_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NONLINEARMODELGRAPHSLabel       matlab.ui.control.Label
        LINEARMODELGRAPHSLabel          matlab.ui.control.Label
        SYSTEMSPARAMETERSLabel          matlab.ui.control.Label
        ForceinNewtonsEditField         matlab.ui.control.NumericEditField
        ForceinNewtonsEditFieldLabel    matlab.ui.control.Label
        LengthofcraneinmetersEditField  matlab.ui.control.NumericEditField
        LengthofcraneinmetersEditFieldLabel  matlab.ui.control.Label
        MassofloadinkgEditField         matlab.ui.control.NumericEditField
        MassofloadinkgEditFieldLabel    matlab.ui.control.Label
        MassofcartinkgEditField         matlab.ui.control.NumericEditField
        MassofcartinkgEditFieldLabel    matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        ChooseForceTypeDropDown         matlab.ui.control.DropDown
        ChooseForceTypeDropDownLabel    matlab.ui.control.Label
        theta_2                         matlab.ui.control.UIAxes
        position_2                      matlab.ui.control.UIAxes
        force                           matlab.ui.control.UIAxes
        position                        matlab.ui.control.UIAxes
        theta                           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            g = 9.81; %gravity constant
            % Consant Force Parameters
            tf = 70; %final time for force
            ts = tf/2; %switch time
            f = app.ForceinNewtonsEditField.Value; % force in Newtons
            M = app.MassofcartinkgEditField.Value; %mass of cart in kg
            m = app.MassofloadinkgEditField.Value; %mass of load in kg
            l = app.LengthofcraneinmetersEditField.Value; %lenght of crane in meters
            if app.ChooseForceTypeDropDown.Value == "Constant Force"
                cf = 1;
                hf = 0;
                lf = 0;
            end
           if app.ChooseForceTypeDropDown.Value == "Linear Force"
                cf = 0;
                hf = 0;
                lf = 1;
            end

           if app.ChooseForceTypeDropDown.Value == "Harmonic Force"
                cf = 0;
                hf = 1;
                lf = 0;
            end


            mdl = "gantry_crane_model";
            open_system(mdl);
            in =Simulink.SimulationInput(mdl);
            in = in.setVariable('g',g);
            in = in.setVariable('tf',tf);
            in = in.setVariable('ts',ts);
            in = in.setVariable('f',f);
            in = in.setVariable('M',M);
            in = in.setVariable('m',m);
            in = in.setVariable('l',l);
            in = in.setVariable('cf',cf);
            in = in.setVariable('hf',hf);
            in = in.setVariable('lf',lf);
            in = in.setVariable('M1',m);
            in = in.setVariable('m1',M);
            scopeConfig = get_param('gantry_crane_model/Scope1','ScopeConfiguration');
            scopeConfig.DataLogging = true;
            scopeConfig.DataLoggingSaveFormat = 'Dataset';
            out = sim(in);
            position_data = out.ScopeData{1}.Values.Data(:,1);
            position_time = out.ScopeData{1}.Values.Time;
            plot(app.position,position_time,position_data);
      
            scopeConfig = get_param('gantry_crane_model/Scope3','ScopeConfiguration');
            scopeConfig.DataLogging = true;
            scopeConfig.DataLoggingSaveFormat = 'Dataset';
            theta_data = out.ScopeData1{1}.Values.Data(:,1);
            theta_time =  out.ScopeData1{1}.Values.Time;
            plot(app.theta,theta_time,theta_data);


            scopeConfig = get_param('gantry_crane_model/Scope2','ScopeConfiguration');
            scopeConfig.DataLogging = true;
            scopeConfig.DataLoggingSaveFormat = 'Dataset';
            force_data = out.force_scope{1}.Values.Data(:,1);
            force_time =  out.force_scope{1}.Values.Time;
            plot(app.force,force_time,force_data);


            scopeConfig = get_param('gantry_crane_model/Scope6','ScopeConfiguration');
            scopeConfig.DataLogging = true;
            scopeConfig.DataLoggingSaveFormat = 'Dataset';
            non_linear_position_data = out.non_linear_position{1}.Values.Data(:,1);
            non_linear_position_time =  out.non_linear_position{1}.Values.Time;
            plot(app.position_2,non_linear_position_time,non_linear_position_data);

            scopeConfig = get_param('gantry_crane_model/Scope7','ScopeConfiguration');
            scopeConfig.DataLogging = true;
            scopeConfig.DataLoggingSaveFormat = 'Dataset';
            non_linear_theta_data = out.non_linear_theta{1}.Values.Data(:,1);
            non_linear_theta_time =  out.non_linear_theta{1}.Values.Time;
            plot(app.theta_2,non_linear_theta_time,non_linear_theta_data);


        end

        % Button down function: theta
        function thetaButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.theta = uiaxes(app.UIFigure);
            title(app.theta, 'Theta Plot')
            xlabel(app.theta, 'Time')
            ylabel(app.theta, 'Theta')
            zlabel(app.theta, 'Z')
            app.theta.ButtonDownFcn = createCallbackFcn(app, @thetaButtonDown, true);
            app.position = uiaxes(app.UIFigure);
            title(app.position, 'Position with Time')
            xlabel(app.position, {'Time'; ''})
            ylabel(app.position, {'Posiiton'; ''})
            zlabel(app.position, 'Z')
            app.force = uiaxes(app.UIFigure);
            title(app.force, 'Applied Force')
            xlabel(app.force, 'Time')
            ylabel(app.force, 'Newton')
            zlabel(app.force, 'Z')
            app.position_2 = uiaxes(app.UIFigure);
            title(app.position_2, 'Position with Time')
            xlabel(app.position_2, 'Time')
            ylabel(app.position_2, 'Position')
            zlabel(app.position_2, 'Z')
            app.theta_2 = uiaxes(app.UIFigure);
            title(app.theta_2, 'Theta Plot')
            xlabel(app.theta_2, 'Time')
            ylabel(app.theta_2, 'Theta')
            zlabel(app.theta_2, 'Z')
            app.ChooseForceTypeDropDownLabel = uilabel(app.UIFigure);
            app.ChooseForceTypeDropDownLabel.Text = 'Choose Force Type';
            app.ChooseForceTypeDropDown = uidropdown(app.UIFigure);
            app.ChooseForceTypeDropDown.Items = {'Constant Force', 'Linear Force', 'Harmonic Force'};
            app.ChooseForceTypeDropDown.Value = 'Constant Force';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.MassofcartinkgEditFieldLabel = uilabel(app.UIFigure);
            app.MassofcartinkgEditFieldLabel.Text = 'Mass of cart in kg';
            app.MassofcartinkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassofloadinkgEditFieldLabel = uilabel(app.UIFigure);
            app.MassofloadinkgEditFieldLabel.Text = 'Mass of load in kg';
            app.MassofloadinkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthofcraneinmetersEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofcraneinmetersEditFieldLabel.Text = 'Length of crane in meters';
            app.LengthofcraneinmetersEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForceinNewtonsEditFieldLabel = uilabel(app.UIFigure);
            app.ForceinNewtonsEditFieldLabel.Text = 'Force in Newtons';
            app.ForceinNewtonsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SYSTEMSPARAMETERSLabel = uilabel(app.UIFigure);
            app.SYSTEMSPARAMETERSLabel.Text = 'SYSTEMS PARAMETERS';
            app.LINEARMODELGRAPHSLabel = uilabel(app.UIFigure);
            app.LINEARMODELGRAPHSLabel.Text = 'LINEAR MODEL GRAPHS';
            app.NONLINEARMODELGRAPHSLabel = uilabel(app.UIFigure);
            app.NONLINEARMODELGRAPHSLabel.Text = 'NON-LINEAR MODEL GRAPHS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gantry_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end